classdef classifierGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FormattrainingsetfortrainingButton  matlab.ui.control.Button
        TrainclassifierButton          matlab.ui.control.Button
        CloseButton                    matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        ClassifierparametersTab        matlab.ui.container.Tab
        PostprocessingDropDown         matlab.ui.control.DropDown
        PostprocessingDropDownLabel    matlab.ui.control.Label
        ClassiDetailsLabel             matlab.ui.control.Label
        PostprocessingfunctionhandleEditField  matlab.ui.control.EditField
        PostprocessingfunctionhandleEditFieldLabel  matlab.ui.control.Label
        ChannelindexindicesforimageclassificationEditField  matlab.ui.control.EditField
        ChannelindexindicesforimageclassificationEditFieldLabel  matlab.ui.control.Label
        SpaceseparatedclassnamesEditField  matlab.ui.control.EditField
        SpaceseparatedclassnamesLabel  matlab.ui.control.Label
        ClassifieroutputDropDown       matlab.ui.control.DropDown
        ClassifieroutputDropDownLabel  matlab.ui.control.Label
        TypeDropDown                   matlab.ui.control.DropDown
        TypeLabel                      matlab.ui.control.Label
        ClassifiertrainingparametersTab  matlab.ui.container.Tab
        UITrainingTable                matlab.ui.control.Table
        ClassifiertrainingandtestsetTab  matlab.ui.container.Tab
        SaveButton                     matlab.ui.control.Button
    end


    properties (Access = private)
        Data % Description
        callingApp % Description
    end

    methods (Access = private)

        function  displayClassi(app) % displays the data from classi as they are

            % classi type
            typelist=app.Data.classlist{:,2};
            classiObj=app.Data.classiObj;
            app.TypeDropDown.Value=typelist{classiObj.typeid};

            % classi description
            app.ClassiDetailsLabel.Text=app.Data.classlist{classiObj.typeid,3};

            % output
            if classiObj.typeid==4 || classiObj.typeid==13 % timeseries classifiers
                app.ClassifieroutputDropDown.Enable='on';
                app.ClassifieroutputDropDown.Items={'Sequence-to-sequence' 'Sequence-to-one'};
                app.ClassifieroutputDropDown.Value= app.ClassifieroutputDropDown.Items(classiObj.output+1);
            else
                app.ClassifieroutputDropDown.Items={};
                app.ClassifieroutputDropDown.Enable='off' ;
            end

            % channels
            app.ChannelindexindicesforimageclassificationEditField.Value=num2str(classiObj.channel);

            % classes
            if classiObj.typeid==8
                classiObj.classes={'background','cell'};
            end
            if classiObj.typeid==11 || classiObj.typeid==12 % image regression : no classes needed
                   classiObj.classes={}; 
                %   app.SpaceseparatedclassnamesEditField
                app.SpaceseparatedclassnamesEditField.Enable='off';
            else
                
            end

            if numel(classiObj.classes)
                str='';
                for i=1:numel(classiObj.classes)
                    str=[str classiObj.classes{i} ' '];
                end
                app.SpaceseparatedclassnamesEditField.Value=str;
            else
                app.SpaceseparatedclassnamesEditField.Value='';
            end

            % postprocessing functions 
            outputstr='';
            % outut functions
            if classiObj.typeid==2 || classiObj.typeid==8 %numel(classiObj.outputType)
                %   aa=classiObj.outputFun
                %   class(aa)
                app.PostprocessingDropDown.Enable='on';
                if numel(classiObj.outputType)
                    outputstr=classiObj.outputType;
                else
                    outputstr='None';
                end
                app.PostprocessingDropDown.Value=outputstr;
            else
                app.PostprocessingDropDown.Enable='off';
            end

            if strcmp(outputstr,'postprocessing') 
                
                app.PostprocessingfunctionhandleEditField.Enable='on';
                if  numel(classiObj.outputFun)
                app.PostprocessingfunctionhandleEditField.Value=classiObj.outputFun;
                else
                app.PostprocessingfunctionhandleEditField.Value='@post';     
                end
                
            else
                app.PostprocessingfunctionhandleEditField.Enable='off';
                app.PostprocessingfunctionhandleEditField.Value='';
            end
    

            % HERE
        end

        function  displayTraining(app)
            % displays training parameters as an editable table
            shortParam=app.Data.shortParam;
            % dat=struct2table(shortParam,'AsArray',true)
            fi=fieldnames(shortParam);
            Data={};
            for i=1:numel(fi)
                tmp=num2str(shortParam.(fi{i}));
                Data=[Data tmp];
              %   Data=[Data i];
            end

            t=app.UITrainingTable;
            
            t.Data=Data;
            t.ColumnName=fi;
            t.ColumnEditable=true;
            
            typeid=app.Data.classiObj.typeid;
            
            switch typeid
                case 4 % lstm+cnn image sequence
                  t.ColumnFormat={[] {'adam' 'sgdm'} {'resnet50' 'googlenet'} [] [] [] {'every-epoch'} [] [] [] [] {'gpu'}};
                case {2,8} % pixel classification
                  t.ColumnFormat={[] {'adam' 'sgdm'} {'resnet50' 'googlenet'} [] [] [] {'every-epoch'} [] [] [] [] {'gpu'}};
                case 13
                  t.ColumnFormat={[] {'adam' 'sgdm'} {'resnet50' 'googlenet'} [] [] [] {'every-epoch'} [] [] [] [] {'gpu'}};  
            end 
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, classiObj)
            % HERE
            % app.callingApp=callingApp;
            % displayNodes(callingApp)
            %  gatherVarsFromWorkspace(callingApp)


            if nargin==1
                classiObj=classi;
                classiObj.path='';
                classiObj.strid='No input classi was provided';

            end

            app.UIFigure.Name=[classiObj.strid];
            app.Data.classiObj=classiObj;

            str=which('shallowNew.m');
            [pth file ext]=fileparts(str);
            str=[pth '/classification/classlist.mat'];
            load(str);
            app.Data.classlist=classlist;
            app.TypeDropDown.Items=classlist{:,2};

            if nargin>1
                displayClassi(app)
            end

     
            % oading training parameters
            if isfile([classiObj.path '/trainingParam.mat'])
                load([classiObj.path '/trainingParam.mat']) % loading trainingParam variable

               % disp(trainingParam);

                app.Data.trainingParam=trainingParam;
            else
                disp('Training parameters do not exist: setting up defaults values!');
                trainingParam=struct('imageclassifier',[],'rois',1:numel(classiObj.roi),'method','adam','network','resnet50',...
                    'MinBatchSize',8,'MaxEpochs',100,'InitialLearningRate',0.001,'Shuffle','every-epoch','split',0.9,...
                    'translateAugmentation',[-7 7],'rotateAugmentation',[-20 20],'regularization',0.005,'ExecutionEnvironment','gpu');

            end

            shortParam=trainingParam;
            
            excludestr=fieldnames(shortParam);
            excludestr=excludestr(contains(excludestr,{'rois','imageclassifier','roisID','classes'}));
            shortParam=rmfield(shortParam,excludestr);

            app.Data.shortParam=shortParam;


        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            classiSave(app.Data.classiObj);
        end

        % Button down function: ClassifiertrainingparametersTab
        function ClassifiertrainingparametersTabButtonDown(app, event)
    displayTraining(app)

        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            value = app.TypeDropDown.Value;
            pix=find(contains(app.TypeDropDown.Items,value));
            app.Data.classiObj.typeid=pix;
            displayClassi(app);
        end

        % Value changed function: ClassifieroutputDropDown
        function ClassifieroutputDropDownValueChanged(app, event)
            value = app.ClassifieroutputDropDown.Value;
             pix=find(contains(app.ClassifieroutputDropDown.Items,value));
            app.Data.classiObj.output=pix-1;
            displayClassi(app);
        end

        % Value changed function: 
        % ChannelindexindicesforimageclassificationEditField
        function ChannelindexindicesforimageclassificationEditFieldValueChanged(app, event)
            value = app.ChannelindexindicesforimageclassificationEditField.Value;
            app.Data.classiObj.channel=str2num(value);
        end

        % Value changed function: SpaceseparatedclassnamesEditField
        function SpaceseparatedclassnamesEditFieldValueChanged(app, event)
            value = app.SpaceseparatedclassnamesEditField.Value;
             classes = textscan(value,'%s','Delimiter',' ');
             app.Data.classiObj.classes=classes{1};
        end

        % Callback function
        function PostprocessingfunctionhandleEditFieldValueChanged(app, event)
           value =PostprocessingfunctionhandleEditFieldValueChanged.Value;
            app.Data.classiObj.outputFun=str2func(value);
        end

        % Value changed function: PostprocessingDropDown
        function PostprocessingDropDownValueChanged(app, event)
            value = app.PostprocessingDropDown.Value;
            if strcmp(value,'None')
                value='';
            end
            app.Data.classiObj.outputType=value;
            displayClassi(app);
        end

        % Value changed function: 
        % PostprocessingfunctionhandleEditField
        function PostprocessingfunctionhandleEditFieldValueChanged2(app, event)
            value = app.PostprocessingfunctionhandleEditField.Value;
            app.Data.classiObj.outputFun=value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ClassifierparametersTab = uitab(app.TabGroup);
            app.ClassifierparametersTab.Title = 'Classifier parameters';
            app.TypeLabel = uilabel(app.ClassifierparametersTab);
            app.TypeLabel.Text = 'Type:';
            app.TypeDropDown = uidropdown(app.ClassifierparametersTab);
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.ClassifieroutputDropDownLabel = uilabel(app.ClassifierparametersTab);
            app.ClassifieroutputDropDownLabel.Text = 'Classifier output:';
            app.ClassifieroutputDropDown = uidropdown(app.ClassifierparametersTab);
            app.ClassifieroutputDropDown.ValueChangedFcn = createCallbackFcn(app, @ClassifieroutputDropDownValueChanged, true);
            app.SpaceseparatedclassnamesLabel = uilabel(app.ClassifierparametersTab);
            app.SpaceseparatedclassnamesLabel.Text = 'Space-separated class names: ';
            app.SpaceseparatedclassnamesEditField = uieditfield(app.ClassifierparametersTab, 'text');
            app.SpaceseparatedclassnamesEditField.ValueChangedFcn = createCallbackFcn(app, @SpaceseparatedclassnamesEditFieldValueChanged, true);
            app.ChannelindexindicesforimageclassificationEditFieldLabel = uilabel(app.ClassifierparametersTab);
            app.ChannelindexindicesforimageclassificationEditFieldLabel.Text = 'Channel index (indices) for image classification:';
            app.ChannelindexindicesforimageclassificationEditField = uieditfield(app.ClassifierparametersTab, 'text');
            app.ChannelindexindicesforimageclassificationEditField.ValueChangedFcn = createCallbackFcn(app, @ChannelindexindicesforimageclassificationEditFieldValueChanged, true);
            app.PostprocessingfunctionhandleEditFieldLabel = uilabel(app.ClassifierparametersTab);
            app.PostprocessingfunctionhandleEditFieldLabel.Text = 'Post-processing function handle:';
            app.PostprocessingfunctionhandleEditField = uieditfield(app.ClassifierparametersTab, 'text');
            app.PostprocessingfunctionhandleEditField.ValueChangedFcn = createCallbackFcn(app, @PostprocessingfunctionhandleEditFieldValueChanged2, true);
            app.ClassiDetailsLabel = uilabel(app.ClassifierparametersTab);
            app.ClassiDetailsLabel.Text = 'ClassiDetails';
            app.PostprocessingDropDownLabel = uilabel(app.ClassifierparametersTab);
            app.PostprocessingDropDownLabel.Text = 'Post-processing';
            app.PostprocessingDropDown = uidropdown(app.ClassifierparametersTab);
            app.PostprocessingDropDown.Items = {'None', 'segmentation', 'postprocessing', ''};
            app.PostprocessingDropDown.ValueChangedFcn = createCallbackFcn(app, @PostprocessingDropDownValueChanged, true);
            app.PostprocessingDropDown.Value = 'None';
            app.ClassifiertrainingparametersTab = uitab(app.TabGroup);
            app.ClassifiertrainingparametersTab.Title = 'Classifier training parameters';
            app.ClassifiertrainingparametersTab.ButtonDownFcn = createCallbackFcn(app, @ClassifiertrainingparametersTabButtonDown, true);
            app.UITrainingTable = uitable(app.ClassifiertrainingparametersTab);
            app.UITrainingTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITrainingTable.RowName = {};
            app.ClassifiertrainingandtestsetTab = uitab(app.TabGroup);
            app.ClassifiertrainingandtestsetTab.Title = 'Classifier training and test set';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.TrainclassifierButton = uibutton(app.UIFigure, 'push');
            app.TrainclassifierButton.Text = 'Train classifier';
            app.FormattrainingsetfortrainingButton = uibutton(app.UIFigure, 'push');
            app.FormattrainingsetfortrainingButton.Text = 'Format training set for training';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = classifierGUI(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end