classdef detector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        FileMenu                   matlab.ui.container.Menu
        LoaddatagroupsMenu         matlab.ui.container.Menu
        SavedatagroupsasMenu       matlab.ui.container.Menu
        TabGroup                   matlab.ui.container.TabGroup
        SelectdataparametersTab    matlab.ui.container.Tab
        DataPanel                  matlab.ui.container.Panel
        ParametersPanel            matlab.ui.container.Panel
        DatagroupsPanel            matlab.ui.container.Panel
        Lamp                       matlab.ui.control.Lamp
        SaveselectedgroupButton    matlab.ui.control.Button
        AssignselectedgroupparamtoallButton  matlab.ui.control.Button
        RenamegroupEditField       matlab.ui.control.EditField
        RenamegroupEditFieldLabel  matlab.ui.control.Label
        DeleteselectedgroupButton  matlab.ui.control.Button
        NewgroupButton             matlab.ui.control.Button
        DatagroupsListBox          matlab.ui.control.ListBox
        DivisioncountingTab        matlab.ui.container.Tab
        RLSAnalysisTab             matlab.ui.container.Tab
        CellsurvivalanalysisTab    matlab.ui.container.Tab
    end

    
    properties (Access = private)
        Data % Description
    end
    
    methods (Access = private)


    end
    
    methods (Access = public)
        
         function param = newGroup(app)
            tip={'Choose the type of division classification (bud or div) ',...
            'Set the frame interval (min)',...
            'Perform postprocessing or not',...
            'Detect errors or not',...
            'Lifespan arrest threshold( frames)',...
            'Number of cosnsecutive dead frames before death is assigned',...
            'Number of frames before traps is declared empty',...
            '?',...
            'Frames array used for analysis'};
        
        param=struct('classiftype',{{'bud','div','bud'}},...
            'timeRate',5,...
            'postProcessing',true,...
            'errorDetection',true,...
            'ArrestThreshold',175,...
            'DeathThreshold',3,...
            'EmptyThresholdDiscard',500,...
            'EmptyThresholdNext',100,...
            'Frames',[-1],...
            'tip',{tip});
         end

        function display(app)
             
            groups = app.Data.groups; 

            items= {groups.Name};

               if numel(app.DatagroupsListBox.Value)
                   aa=app.DatagroupsListBox.Value;
                   pix=find(matches(items,aa));
                  
                   if numel(pix)==0
                        pix=1;
                   else
                        pix=pix(1);
                   end
               else
                    pix=1;
               end

              app.DatagroupsListBox.Items=items;
              app.DatagroupsListBox.Value=items{pix};
                
%                    app.Data.selectedgroup=pix;
%                    pix=find(matches(app.DatagroupsListBox.Items,app.DatagroupsListBox.Value));
              param=app.Data.groups(pix).Param;

                   %here
                  tip=param.tip;
                  param=rmfield(param,'tip');
                  app.ParametersPanel.UserData=[];
                  delete(app.ParametersPanel.Children);
                  struct2GUI(param,[10 310],'Handle',app.ParametersPanel,'Tip',tip,'Flag',app.Lamp);
                  app.ParametersPanel.UserData.tip=tip;
                  app.RenamegroupEditField.Value=aa;
       %          aaa=app.ParametersPanel.UserData
            %   else
               %     disp('no group selected')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.Data=[];
      
         param = newGroup(app);
          app.Data.groups=struct('Name','group1','Data',[],'Param',param);
          app.Data.groups(2)= app.Data.groups;
           app.Data.groups(2).Name= 'group2';
         % app.Data.selectedgroup=[];

          display(app)
        end

        % Value changed function: DatagroupsListBox
        function DatagroupsListBoxValueChanged(app, event)
           tmp=app.DatagroupsListBox.Value;
            app.Data.selectedgroup = tmp;
           display(app)
        end

        % Value changed function: RenamegroupEditField
        function RenamegroupEditFieldValueChanged(app, event)
            value = app.RenamegroupEditField.Value;
            
            if numel(app.DatagroupsListBox.Value)
                    pix=find(matches(app.DatagroupsListBox.Items,app.DatagroupsListBox.Value));
                    pix=pix(1);
                    app.Data.groups(pix).Name=value;
                    app.DatagroupsListBox.Items{pix}=value;
                    app.DatagroupsListBox.Value=value; 
            end
        end

        % Button pushed function: NewgroupButton
        function NewgroupButtonPushed(app, event)
                          pix=numel(app.DatagroupsListBox.Items);

                          sel=find(matches(app.DatagroupsListBox.Items,app.DatagroupsListBox.Value));
                          % take param value from selected position
% here
                           if numel(sel)
                               param= app.Data.groups(sel).Param; 
                           else
                               param = newGroup(app);

                           end

                          app.DatagroupsListBox.Items=[app.DatagroupsListBox.Items {['group' num2str(pix+1)]}];
                          app.DatagroupsListBox.Value=app.DatagroupsListBox.Items{end};

                       %  aa= size(app.Data.groups)

                          app.Data.groups(pix+1)=app.Data.groups(pix);

                          app.Data.groups(pix+1).Name=app.DatagroupsListBox.Value;
                          app.Data.groups(pix+1).Param=param;
                          display(app)
                           
                          
        end

        % Button pushed function: DeleteselectedgroupButton
        function DeleteselectedgroupButtonPushed(app, event)
             sel=find(matches(app.DatagroupsListBox.Items,app.DatagroupsListBox.Value));
sel=sel(1);

                           if numel(sel)
li=setxor(1:numel(app.DatagroupsListBox.Items),sel);
app.DatagroupsListBox.Items=app.DatagroupsListBox.Items(li);
app.Data.groups= app.Data.groups(li);
app.DatagroupsListBox.Value=app.DatagroupsListBox.Items{1};
                           end
                           display(app)
        end

        % Button pushed function: SaveselectedgroupButton
        function SaveselectedgroupButtonPushed(app, event)
                   sel=find(matches(app.DatagroupsListBox.Items,app.DatagroupsListBox.Value));
                   sel=sel(1);

                           if numel(sel)
                              app.Data.groups(sel).Param=app.ParametersPanel.UserData;
                              app.Lamp.Color=[0 1 0];
                           end
        end

        % Button pushed function: AssignselectedgroupparamtoallButton
        function AssignselectedgroupparamtoallButtonPushed(app, event)
             sel=find(matches(app.DatagroupsListBox.Items,app.DatagroupsListBox.Value));
               sel=sel(1);

                           if numel(sel)
                              li=setxor(1:numel(app.DatagroupsListBox.Items),sel);
                              if numel(li)
                                        for i=li
                                                app.Data.groups(i).Param=app.Data.groups(sel).Param;
                                        end
                              end
                           else
                            uialert(app.UIFigure,'First select a data group !','Warning');
                           end

        end

        % Menu selected function: LoaddatagroupsMenu
        function LoaddatagroupsMenuSelected(app, event)
      
   [file,path] = uigetfile('*.mat','Select a shallow project',pwd);
   if isequal(file,0)
   disp('User selected Cancel')
   return;
   else
   disp(['User selected ', fullfile(path, file)]); 
   filename=fullfile(path, file);
   
   load(filename);
   app.Data.groups=groups;
   display(app)
 
   end

        end

        % Menu selected function: SavedatagroupsasMenu
        function SavedatagroupsasMenuSelected(app, event)
              [file,path] = uiputfile('*.mat','Choose a data group filename',pwd);
   if isequal(file,0)
   disp('User selected Cancel')
   return;
   else
   disp(['User selected ', fullfile(path, file)]); 
   filename=fullfile(path, file);
   
   groups=app.Data.groups;
   save(filename, 'groups');
   end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File...';
            app.LoaddatagroupsMenu = uimenu(app.FileMenu);
            app.LoaddatagroupsMenu.MenuSelectedFcn = createCallbackFcn(app, @LoaddatagroupsMenuSelected, true);
            app.LoaddatagroupsMenu.Text = 'Load data groups...';
            app.SavedatagroupsasMenu = uimenu(app.FileMenu);
            app.SavedatagroupsasMenu.MenuSelectedFcn = createCallbackFcn(app, @SavedatagroupsasMenuSelected, true);
            app.SavedatagroupsasMenu.Text = 'Save data groups as...';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SelectdataparametersTab = uitab(app.TabGroup);
            app.SelectdataparametersTab.Title = 'Select data & parameters';
            app.DatagroupsPanel = uipanel(app.SelectdataparametersTab);
            app.DatagroupsPanel.Title = 'Data groups';
            app.DatagroupsListBox = uilistbox(app.DatagroupsPanel);
            app.DatagroupsListBox.Items = {'group01', 'group02'};
            app.DatagroupsListBox.ValueChangedFcn = createCallbackFcn(app, @DatagroupsListBoxValueChanged, true);
            app.DatagroupsListBox.Value = 'group01';
            app.NewgroupButton = uibutton(app.DatagroupsPanel, 'push');
            app.NewgroupButton.ButtonPushedFcn = createCallbackFcn(app, @NewgroupButtonPushed, true);
            app.NewgroupButton.Text = 'New group';
            app.DeleteselectedgroupButton = uibutton(app.DatagroupsPanel, 'push');
            app.DeleteselectedgroupButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteselectedgroupButtonPushed, true);
            app.DeleteselectedgroupButton.Text = 'Delete selected group';
            app.RenamegroupEditFieldLabel = uilabel(app.DatagroupsPanel);
            app.RenamegroupEditFieldLabel.Text = 'Rename group:';
            app.RenamegroupEditField = uieditfield(app.DatagroupsPanel, 'text');
            app.RenamegroupEditField.ValueChangedFcn = createCallbackFcn(app, @RenamegroupEditFieldValueChanged, true);
            app.AssignselectedgroupparamtoallButton = uibutton(app.DatagroupsPanel, 'push');
            app.AssignselectedgroupparamtoallButton.ButtonPushedFcn = createCallbackFcn(app, @AssignselectedgroupparamtoallButtonPushed, true);
            app.AssignselectedgroupparamtoallButton.Text = 'Assign selected group param to all';
            app.SaveselectedgroupButton = uibutton(app.DatagroupsPanel, 'push');
            app.SaveselectedgroupButton.ButtonPushedFcn = createCallbackFcn(app, @SaveselectedgroupButtonPushed, true);
            app.SaveselectedgroupButton.Text = 'Save selected group';
            app.Lamp = uilamp(app.DatagroupsPanel);
            app.ParametersPanel = uipanel(app.SelectdataparametersTab);
            app.ParametersPanel.Title = 'Parameters';
            app.DataPanel = uipanel(app.SelectdataparametersTab);
            app.DataPanel.Title = 'Data';
            app.DivisioncountingTab = uitab(app.TabGroup);
            app.DivisioncountingTab.Title = 'Division counting';
            app.RLSAnalysisTab = uitab(app.TabGroup);
            app.RLSAnalysisTab.Title = 'RLS Analysis';
            app.CellsurvivalanalysisTab = uitab(app.TabGroup);
            app.CellsurvivalanalysisTab.Title = 'Cell survival analysis';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = detector

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end