classdef score < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ScoreAppUIFigure           matlab.ui.Figure
        FileMenu                   matlab.ui.container.Menu
        DisplayMenu                matlab.ui.container.Menu
        DisplaysettingsMenu        matlab.ui.container.Menu
        ROIsMenu                   matlab.ui.container.Menu
        DatasettingsMenu           matlab.ui.container.Menu
        DataMenu                   matlab.ui.container.Menu
        DataPanel                  matlab.ui.container.Panel
        UIDataAxes                 matlab.ui.control.UIAxes
        DataSettingsPanel          matlab.ui.container.Panel
        UIDataTable                matlab.ui.control.Table
        ROisPanel                  matlab.ui.container.Panel
        UIROITable                 matlab.ui.control.Table
        DisplaysettingsPanel       matlab.ui.container.Panel
        FrameSlider                matlab.ui.control.Slider
        FrameSliderLabel           matlab.ui.control.Label
        ZoomSlider                 matlab.ui.control.Slider
        ZoomLabel                  matlab.ui.control.Label
        NewContour                 matlab.ui.control.Button
        ObjectPicker2              matlab.ui.control.ColorPicker
        ColorPicker2Label          matlab.ui.control.Label
        ChannelColorPicker         matlab.ui.control.ColorPicker
        ColorPickerLabel           matlab.ui.control.Label
        HighDisplaySlider          matlab.ui.control.Slider
        HighLabel                  matlab.ui.control.Label
        LowDisplaySlider           matlab.ui.control.Slider
        LowLabel                   matlab.ui.control.Label
        UIDisplayAnnotationsTable  matlab.ui.control.Table
        UIChannelTable             matlab.ui.control.Table
        UIDisplayAxes              matlab.ui.control.UIAxes
        UIImageAxes                matlab.ui.control.UIAxes
    end


    properties (Access = public)
        content = struct('ROIList',[]); % Description
    end
    
    properties (Access = private)
        SliderTickLabels % Description
    end

    methods (Access = private)

        % Toggle the visibility of a panel and update menu status
        function newVisibility = toggleVisibility(~, menuItem)
            if strcmp(menuItem.Checked, 'on')
                menuItem.Checked = 'off';
                newVisibility = 'off';
            else
                menuItem.Checked = 'on';
                newVisibility = 'on';
            end
        end

        function resizeUIImageAxes(app)
            % Position initiale et dimensions maximales
            baseX = 10;  % Coordonnée X de départ
            baseY = 10;  % Coordonnée Y de départ
            maxWidth = app.ScoreAppUIFigure.Position(3) - baseX;  % Largeur maximale
            maxHeight = app.ScoreAppUIFigure.Position(4) - baseY; % Hauteur maximale

            % Ajuster la taille en fonction de la visibilité des panneaux
            if strcmp(app.DisplaysettingsPanel.Visible, 'on') || strcmp(app.ROisPanel.Visible, 'on') || strcmp(app.DataSettingsPanel.Visible, 'on')
                maxWidth = maxWidth - app.DisplaysettingsPanel.Position(3);
                baseX=baseX+app.DisplaysettingsPanel.Position(3);
            end

            if strcmp(app.DataPanel.Visible, 'on')
                maxWidth = maxWidth - app.DataPanel.Position(3)-10;
            end

            % Mettre à jour la position et la taille de UIImageAxes
            app.UIImageAxes.Position = [baseX, baseY, maxWidth, maxHeight];
        end

       function displayROIs(app)
    % Vérifie si des ROIs sont présentes
    if isempty(app.content.ROIList)
        app.UIROITable.Data = {}; % Vide la table si aucune ROI
        return;
    end

    % Préparer les colonnes : Display, Name, Size
    numROIs = numel(app.content.ROIList);
    tableData = cell(numROIs, 3);

    for i = 1:numROIs
        roi = app.content.ROIList{i}; % Récupérer l'instance de la ROI
        tableData{i, 1} = false; % Désactiver l'affichage par défaut
        tableData{i, 2} = roi.id; % Nom de la ROI
        tableData{i, 3} = sprintf('%dx%d', size(roi.image, 1), size(roi.image, 2)); % Taille de l'image
    end

    % Mettre à jour la table
    app.UIROITable.Data = tableData;
    app.UIROITable.ColumnName = {'Display', 'Name', 'Size'};
    
    % Ajuster les largeurs des colonnes
    app.UIROITable.ColumnWidth = {50, 'auto', 60};

    % Définir un callback pour gérer les sélections
    app.UIROITable.CellEditCallback = @(src, event) app.handleROITableEdit(event);
end

function handleROITableEdit(app, event)
    % Callback pour s'assurer qu'une seule ROI est sélectionnée
    if event.Indices(2) == 1 % Si la colonne "Display" est modifiée
        % Désactiver toutes les cases sauf celle cliquée
        numROIs = size(app.UIROITable.Data, 1);
        for i = 1:numROIs
            app.UIROITable.Data{i, 1} = false;
        end

        % Activer uniquement la ligne sélectionnée
        app.UIROITable.Data{event.Indices(1), 1} = true;

        % Mettre à jour la table
        app.UIROITable.Data = app.UIROITable.Data;

        % Optionnel : effectuer des actions basées sur la sélection
        selectedROI = app.content.ROIList{event.Indices(1)};
        disp(['Selected ROI: ', selectedROI.id]);

         displayROIChannels(app);

    end
end
        
function displayROIChannels(app)
    % Vérifie si des ROIs sont présentes dans l'application
    if isempty(app.content.ROIList)
        app.UIChannelTable.Data = {}; % Vide la table si aucune ROI
        return;
    end

    % Trouver l'index de la ROI actuellement sélectionnée
    selectedIndex = find(cell2mat(app.UIROITable.Data(:, 1)), 1); % Cherche la première ROI cochée
    if isempty(selectedIndex)
        app.UIChannelTable.Data = {}; % Vide la table si aucune ROI n'est sélectionnée
        return;
    end

    % Récupérer la ROI sélectionnée
    selectedROI = app.content.ROIList{selectedIndex};

    % Vérifier si la ROI a des canaux disponibles
    if isempty(selectedROI.display.channel)
        app.UIChannelTable.Data = {}; % Vide la table si aucun canal
        return;
    end

    % Préparer les colonnes : Display, Name, Levels, RGB, Weight, Auto
    numChannels = numel(selectedROI.display.channel);
    tableData = cell(numChannels, 6);

    for i = 1:numChannels
        tableData{i, 1} = logical(selectedROI.display.selectedchannel(i)); % Checkbox pour Display
        tableData{i, 2} = selectedROI.display.channel{i}; % Nom du canal
        tableData{i, 3} = sprintf('%.2f %.2f', round(65535*selectedROI.display.displaylim(1, i)), round(65535*selectedROI.display.displaylim(2, i))); % Levels
        tableData{i, 4} = sprintf('%.2f %.2f %.2f', selectedROI.display.rgb(i, :)); % RGB
        tableData{i, 5} = sprintf('%.2f', selectedROI.display.intensity(i, 1)); % Weight
        tableData{i, 6} = false; % Par défaut, "Auto" est désactivé
    end

    % Mettre à jour la table
    app.UIChannelTable.Data = tableData;
    app.UIChannelTable.ColumnName = {' ', 'Name', 'Levels', 'RGB', 'Weight', 'Auto'};
    app.UIChannelTable.ColumnWidth = {40, 100, 120, 100, 80, 40}; % Largeurs des colonnes

    % Rendre les colonnes modifiables
    app.UIChannelTable.ColumnEditable = [true, false, true, true, true, true];

    % Définir un callback pour gérer les modifications de la table
    app.UIChannelTable.CellEditCallback = @(src, event) app.handleChannelTableEdit(event, selectedROI);

    
end

function handleChannelTableEdit(app, event, selectedROI)
    % Callback pour gérer l'édition de la table des canaux
    rowIndex = event.Indices(1); % Index de la ligne modifiée
    colIndex = event.Indices(2); % Index de la colonne modifiée

    switch colIndex
        case 1 % Modification dans la colonne "Display" (checkbox)
            selectedROI.display.selectedchannel(rowIndex) = event.NewData;

        case 3 
            tmp=str2num(event.NewData);
            selectedROI.display.displaylim(:, rowIndex) = double(tmp/65535);
            

        case 4 % Modification dans la colonne "RGB"
            % Valider l'entrée comme un triplet RGB
         rgbValues = str2double(strsplit(event.NewData, {' ', ','}));

        if numel(rgbValues) == 3 && all(~isnan(rgbValues))
               disp('RGB values parsed successfully');
        else
                disp('Invalid RGB input format');
        end

            if numel(rgbValues) == 3 && all(rgbValues >= 0 & rgbValues <= 1)
                selectedROI.display.rgb(rowIndex, :) = rgbValues';
            else
                uialert(app.ScoreAppUIFigure, 'RGB values must be three numbers between 0 and 1.', 'Invalid Input');
                app.displayROIChannels(); % Réinitialiser la table
            end

        case 5 % Modification dans la colonne "Weight"
            % Valider l'entrée comme un nombre positif
            weightValue = str2double(event.NewData);
            if ~isnan(weightValue) && weightValue > 0
                selectedROI.display.intensity(rowIndex) = weightValue;
            else
                uialert(app.ScoreAppUIFigure, 'Weight must be a positive number.', 'Invalid Input');
                app.displayROIChannels(); % Réinitialiser la table
            end

        case 6 % Modification dans la colonne "Auto" (checkbox pour auto-adjust)
            if event.NewData
                % Appliquer un auto-ajustement des niveaux
                selectedROI.computeDisplaylim;
                app.displayROIChannels(); % Mettre à jour la table
            end
    end

    displayChannelFeatures(app)
    event=[];
    event.Value= log10(selectedROI.display.displaylim(1, rowIndex));
    LowDisplaySliderValueChanging(app, event);
    event.Value= log10(selectedROI.display.displaylim(2, rowIndex));
    HighDisplaySliderValueChanging(app, event);
end


 function displayChannelFeatures(app)
    % Vérifier si une ROI est sélectionnée
    if isempty(app.content.ROIList)
        return; % Pas de ROI, rien à afficher
    end

    % Trouver la ROI actuellement sélectionnée
    selectedROIIndex = find(cell2mat(app.UIROITable.Data(:, 1)), 1);
    if isempty(selectedROIIndex)
        return; % Pas de ROI sélectionnée, quitter
    end

    % Récupérer la ROI sélectionnée
    selectedROI = app.content.ROIList{selectedROIIndex};

    % Vérifier si un canal est sélectionné dans la table des canaux
    selectedChannelIndex = app.UIChannelTable.Selection;
    if isempty(selectedChannelIndex) || isempty(selectedChannelIndex(1))
        return; % Pas de canal sélectionné, quitter
    end

    channelIndex = selectedChannelIndex(1); % Index du canal sélectionné

    % Définir les bornes des sliders en échelle logarithmique
    minVal = 1; % On évite 0 car log(0) est indéfini
    maxVal = 65535;
    logMin = log10(minVal);
    logMax = log10(maxVal);

    % Mettre à jour les limites des sliders
    app.LowDisplaySlider.Limits = [logMin, logMax];
    app.HighDisplaySlider.Limits = [logMin, logMax];

    % Appliquer la transformation logarithmique pour récupérer la position correcte
    lowLevel = max(65535*selectedROI.display.displaylim(1, channelIndex), 1);
    highLevel = min(65535*selectedROI.display.displaylim(2, channelIndex), 65535);
    
    app.LowDisplaySlider.Value = log10(lowLevel);
    app.HighDisplaySlider.Value = log10(highLevel);

    % Mettre à jour le color picker RGB
    rgbValues = selectedROI.display.rgb(channelIndex, :);
    app.ChannelColorPicker.Value = rgbValues;

    % Configurer les callbacks MATLAB natifs
    %app.LowDisplaySlider.ValueChangingFcn = @(src, event) app.LowDisplaySliderValueChanging(event, channelIndex);
    %app.HighDisplaySlider.ValueChangingFcn = @(src, event) app.HighDisplaySliderValueChanging(event, channelIndex);

    % Configurer le color picker pour mettre à jour les valeurs RGB
    %app.ChannelColorPicker.ValueChangedFcn = @(src, event) app.updateRGB(channelIndex);
end


function updateChannelTable(app, selectedROI)
    % Vérifier si la table est vide
    if isempty(app.UIChannelTable.Data)
        return;
    end

    % Nombre de canaux
    numChannels = size(selectedROI.display.rgb, 1);
    tableData = app.UIChannelTable.Data;

    for i = 1:numChannels
        % Mettre à jour la colonne "Levels" (conversion en 65535)
        tableData{i, 3} = sprintf('%.2f %.2f', ...
            round(65535 * selectedROI.display.displaylim(1, i)), ...
            round(65535 * selectedROI.display.displaylim(2, i)));
    end

    % Mettre à jour les données de la table
    app.UIChannelTable.Data = tableData;
end

function customizeSliderTicks(app)
    % Positionner les labels sous le slider
    minVal = 1;
    maxVal = 65535;
    tickVals = [1, 10, 100, 1000, 10000, 65535]; % Valeurs log choisies
    
    % Normaliser les valeurs pour le slider (logarithmique)
    logTicks = log10(tickVals);
    
    % Créer les labels sous le slider
    for i = 1:numel(tickVals)
        uilabel(app.DisplaysettingsPanel, ...
            'Text', num2str(tickVals(i)), ...
            'Position', [app.LowDisplaySlider.Position(1) + (i-1)*50, ...
                         app.LowDisplaySlider.Position(2) - 20, 40, 20], ...
            'HorizontalAlignment', 'center');
    end
end

function updateSliderTicks(app)
    % Supprimer les anciens labels s'ils existent
    if ~isempty(app.SliderTickLabels)
        for i = 1:numel(app.SliderTickLabels)
            delete(app.SliderTickLabels{i});
        end
    end

    % Définir les nouvelles valeurs des ticks
    tickVals = [1, 10, 100, 1000, 10000, 65535]; % Valeurs log
    numTicks = numel(tickVals);
    app.SliderTickLabels = cell(1, numTicks);

    % Positionner les labels sous le slider
    sliderPos = app.LowDisplaySlider.Position;
    sliderWidth = sliderPos(3);
    minVal = log10(1);
    maxVal = log10(65535);

    for i = 1:numTicks
        % Normaliser la position des ticks
        normPos = (log10(tickVals(i)) - minVal) / (maxVal - minVal);
        labelX = sliderPos(1) + normPos * sliderWidth - 10; % Décalage centré
        labelY = sliderPos(2) - 20; % Sous le slider

        % Créer le label
        app.SliderTickLabels{i} = uilabel(app.DisplaysettingsPanel, ...
            'Text', num2str(tickVals(i)), ...
            'Position', [labelX, labelY, 40, 20], ...
            'HorizontalAlignment', 'center');
    end
end


    end

    methods (Access = public)
        function addROI(app,roiobj)
            % Vérifier si ROI existe déjà dans app.content.ROIList
            if isempty(app.content.ROIList)
                isPresent = false;
            else
                % Vérifier si une ROI avec le même ID existe
                isPresent = any(cellfun(@(roi) strcmp(roi.id, roiobj.id), app.content.ROIList));
            end

            if isPresent
                disp(['ROI with ID "', roiobj.id, '" is already present. Skipping addition.']);
            else
                % Ajouter la ROI à la liste
                if numel(roiobj.image)==0
                    roiobj.load;
                end

                app.content.ROIList{end+1} = roiobj;
                disp(['Adding ROI: ', roiobj.id]);

                % Mettre à jour l'affichage de la table
                app.displayROIs();
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, roiobj)
            % Connecter les callbacks des menus
            % app.DisplaysettingsMenu.MenuSelectedFcn = @app.DisplaysettingsMenuSelected;
            % app.ROIsMenu.MenuSelectedFcn = @app.ROIsMenuSelected;
            % app.DatasettingsMenu.MenuSelectedFcn = @app.DatasettingsMenuSelected;
            % app.DataMenu.MenuSelectedFcn = @app.DataMenuSelected;

            app.addROI(roiobj);
            % Initialiser l'état des panneaux
            app.DisplaysettingsPanel.Visible = 'on';
            app.ROisPanel.Visible = 'on';
            app.DataSettingsPanel.Visible = 'on';
            app.DataPanel.Visible = 'off';
            app.resizeUIImageAxes();

            % Supprimer les ticks natifs
app.LowDisplaySlider.MajorTicks = 0;
app.HighDisplaySlider.MajorTicks = 0;
            customizeSliderTicks(app);
        end

        % Callback function
        function DisplaysettingsMenuSelected(app, event)

        end

        % Callback function
        function ROIsMenuSelected(app, event)

        end

        % Callback function
        function DatasettingsMenuSelected(app, event)

        end

        % Callback function
        function DataMenuSelected(app, event)

        end

        % Callback function
        function DisplaysettingsMenuSelected2(app, event)

        end

        % Menu selected function: ROIsMenu
        function ROIsMenuSelected2(app, event)
            app.ROisPanel.Visible = app.toggleVisibility(app.ROIsMenu);
            app.resizeUIImageAxes();  % Ajuster UIImageAxes
        end

        % Menu selected function: DatasettingsMenu
        function DatasettingsMenuSelected2(app, event)
            app.DataSettingsPanel.Visible = app.toggleVisibility(app.DatasettingsMenu);
            app.resizeUIImageAxes();  % Ajuster UIImageAxes
        end

        % Menu selected function: DataMenu
        function DataMenuSelected2(app, event)
            app.DataPanel.Visible = app.toggleVisibility(app.DataMenu);
            app.resizeUIImageAxes();  % Ajuster UIImageAxes
        end

        % Menu selected function: DisplaysettingsMenu
        function DisplaysettingsMenuSelected3(app, event)
            app.DisplaysettingsPanel.Visible = app.toggleVisibility(app.DisplaysettingsMenu);
            app.resizeUIImageAxes();  % Ajuster UIImageAxes
        end

        % Selection changed function: UIChannelTable
        function UIChannelTableSelectionChanged(app, event)
            selection = app.UIChannelTable.Selection;
            displayChannelFeatures(app);
        end

        % Value changing function: LowDisplaySlider
        function LowDisplaySliderValueChanging(app, event)
            changingValue = event.Value;
            % Vérifie si une ROI est sélectionnée
    selectedROIIndex = find(cell2mat(app.UIROITable.Data(:, 1)), 1);
    if isempty(selectedROIIndex)
        return;
    end

    % Récupère la ROI sélectionnée
    selectedROI = app.content.ROIList{selectedROIIndex};

    % Vérifie si un canal est sélectionné
    if isempty(app.UIChannelTable.Selection)
        return;
    end

    % Récupère l'index du canal sélectionné
    channelIndex = app.UIChannelTable.Selection(1);
    if isempty(channelIndex)
        return;
    end

    % Met à jour la limite basse en échelle linéaire
    selectedROI.display.displaylim(1, channelIndex) = 10^(event.Value)/65535;

    % Mise à jour dynamique de la table
    updateChannelTable(app, selectedROI);

    % Met à jour l'affichage de l'image en direct
   % updateImageDisplay(app);
            
        end

        % Value changing function: HighDisplaySlider
        function HighDisplaySliderValueChanging(app, event)
            changingValue = event.Value;
             % Vérifie si une ROI est sélectionnée
    selectedROIIndex = find(cell2mat(app.UIROITable.Data(:, 1)), 1);
    if isempty(selectedROIIndex)
        return;
    end

    % Récupère la ROI sélectionnée
    selectedROI = app.content.ROIList{selectedROIIndex};

    % Vérifie si un canal est sélectionné
    if isempty(app.UIChannelTable.Selection)
        return;
    end

    % Récupère l'index du canal sélectionné
    channelIndex = app.UIChannelTable.Selection(1);
    if isempty(channelIndex)
        return;
    end

    % Met à jour la limite haute en échelle linéaire
    selectedROI.display.displaylim(2, channelIndex) = 10^(event.Value)/65535;
       
    updateChannelTable(app, selectedROI);

    % Met à jour l'affichage de l'image en direct
    %updateImageDisplay(app);
        end

        % Value changing function: ZoomSlider
        function ZoomSliderValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changing function: FrameSlider
        function FrameSliderValueChanging(app, event)
            changingValue = event.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ScoreAppUIFigure = uifigure('Visible', 'off');
            app.ScoreAppUIFigure.Name = 'ScoreApp';
            app.FileMenu = uimenu(app.ScoreAppUIFigure);
            app.FileMenu.Text = 'File';
            app.DisplayMenu = uimenu(app.ScoreAppUIFigure);
            app.DisplayMenu.Text = 'Display';
            app.DisplaysettingsMenu = uimenu(app.DisplayMenu);
            app.DisplaysettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @DisplaysettingsMenuSelected3, true);
            app.DisplaysettingsMenu.Text = 'Display settings';
            app.ROIsMenu = uimenu(app.DisplayMenu);
            app.ROIsMenu.MenuSelectedFcn = createCallbackFcn(app, @ROIsMenuSelected2, true);
            app.ROIsMenu.Text = 'ROIs';
            app.DatasettingsMenu = uimenu(app.DisplayMenu);
            app.DatasettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @DatasettingsMenuSelected2, true);
            app.DatasettingsMenu.Text = 'Data settings';
            app.DataMenu = uimenu(app.DisplayMenu);
            app.DataMenu.MenuSelectedFcn = createCallbackFcn(app, @DataMenuSelected2, true);
            app.DataMenu.Text = 'Data';
            app.UIImageAxes = uiaxes(app.ScoreAppUIFigure);
            title(app.UIImageAxes, 'ROI #')
            xlabel(app.UIImageAxes, 'X')
            ylabel(app.UIImageAxes, 'Y')
            zlabel(app.UIImageAxes, 'Z')
            app.DisplaysettingsPanel = uipanel(app.ScoreAppUIFigure);
            app.DisplaysettingsPanel.Title = 'Display settings';
            app.UIDisplayAxes = uiaxes(app.DisplaysettingsPanel);
            title(app.UIDisplayAxes, 'Title')
            xlabel(app.UIDisplayAxes, 'X')
            ylabel(app.UIDisplayAxes, 'Y')
            zlabel(app.UIDisplayAxes, 'Z')
            app.UIChannelTable = uitable(app.DisplaysettingsPanel);
            app.UIChannelTable.ColumnName = {'Display'; 'Name'; 'Levels'; 'RGB'; 'Weight'; 'Auto'};
            app.UIChannelTable.RowName = {};
            app.UIChannelTable.SelectionChangedFcn = createCallbackFcn(app, @UIChannelTableSelectionChanged, true);
            app.UIDisplayAnnotationsTable = uitable(app.DisplaysettingsPanel);
            app.UIDisplayAnnotationsTable.ColumnName = {'Display'; 'Name'; 'RGB'; ''};
            app.UIDisplayAnnotationsTable.RowName = {};
            app.LowLabel = uilabel(app.DisplaysettingsPanel);
            app.LowLabel.Text = 'Low';
            app.LowDisplaySlider = uislider(app.DisplaysettingsPanel);
            app.LowDisplaySlider.ValueChangingFcn = createCallbackFcn(app, @LowDisplaySliderValueChanging, true);
            app.HighLabel = uilabel(app.DisplaysettingsPanel);
            app.HighLabel.Text = 'High';
            app.HighDisplaySlider = uislider(app.DisplaysettingsPanel);
            app.HighDisplaySlider.ValueChangingFcn = createCallbackFcn(app, @HighDisplaySliderValueChanging, true);
            app.ColorPickerLabel = uilabel(app.DisplaysettingsPanel);
            app.ColorPickerLabel.Text = 'Color Picker';
            app.ChannelColorPicker = uicolorpicker(app.DisplaysettingsPanel);
            app.ColorPicker2Label = uilabel(app.DisplaysettingsPanel);
            app.ColorPicker2Label.Text = 'Color Picker2';
            app.ObjectPicker2 = uicolorpicker(app.DisplaysettingsPanel);
            app.NewContour = uibutton(app.DisplaysettingsPanel, 'push');
            app.NewContour.Text = 'New Contour';
            app.ZoomLabel = uilabel(app.DisplaysettingsPanel);
            app.ZoomLabel.Text = 'Zoom';
            app.ZoomSlider = uislider(app.DisplaysettingsPanel);
            app.ZoomSlider.ValueChangingFcn = createCallbackFcn(app, @ZoomSliderValueChanging, true);
            app.FrameSliderLabel = uilabel(app.DisplaysettingsPanel);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.DisplaysettingsPanel);
            app.FrameSlider.ValueChangingFcn = createCallbackFcn(app, @FrameSliderValueChanging, true);
            app.ROisPanel = uipanel(app.ScoreAppUIFigure);
            app.ROisPanel.Title = 'ROis';
            app.UIROITable = uitable(app.ROisPanel);
            app.UIROITable.ColumnName = {'Display'; 'Name'; 'Size'};
            app.UIROITable.RowName = {};
            app.UIROITable.ColumnEditable = [true false false];
            app.DataSettingsPanel = uipanel(app.ScoreAppUIFigure);
            app.DataSettingsPanel.Title = 'Data settings';
            app.UIDataTable = uitable(app.DataSettingsPanel);
            app.UIDataTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UIDataTable.RowName = {};
            app.DataPanel = uipanel(app.ScoreAppUIFigure);
            app.DataPanel.Title = 'Data';
            app.UIDataAxes = uiaxes(app.DataPanel);
            title(app.UIDataAxes, 'Title')
            xlabel(app.UIDataAxes, 'X')
            ylabel(app.UIDataAxes, 'Y')
            zlabel(app.UIDataAxes, 'Z')
            % Show the figure after all components are created
            app.ScoreAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = score(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ScoreAppUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ScoreAppUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ScoreAppUIFigure)
        end
    end
end